classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        sigmaEditField              matlab.ui.control.NumericEditField
        sigmaEditFieldLabel         matlab.ui.control.Label
        segmentationtresholdSlider  matlab.ui.control.Slider
        segmentationtresholdSliderLabel  matlab.ui.control.Label
        edgethresholdSlider         matlab.ui.control.Slider
        edgethresholdSliderLabel    matlab.ui.control.Label
        edgedetectionDropDown       matlab.ui.control.DropDown
        edgedetectionDropDownLabel  matlab.ui.control.Label
        GaussianFilterCheckBox      matlab.ui.control.CheckBox
        ChooseImageButton           matlab.ui.control.Button
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage % gambar ori
        PreprocessedImage
        EdgeImage
        MaskImage
        ResultImage
    end
    
    methods (Access = private)
        function edgeImage = laplace(app, image, threshold)
            H = [0 1 0; 1 -4 1; 0 1 0];
            edgeImage = uint8(convn(double(image), double(H), 'same') > threshold);
        end

        function edgeImage = LoG(app, image, threshold)
            H = fspecial("log",5,0.5);
            edgeImage = uint8(convn(double(image), double(H), 'same') > threshold);
        end
        function edgeImage = sobel(app, image, threshold)
            Sx = [-1 0 1; -2 0 2; -1 0 1];
            Sy = [1 2 1; 0 0 0; -1 -2 -1];
            Jx = convn(double(image), double(Sx), 'same');
            Jy = convn(double(image), double(Sy), 'same');
            edgeImage = uint8(abs(Jx) + abs(Jy) > threshold);
        end

        function edgeImage = prewitt(app, image, threshold)
            Px = [-1 0 1; -1 0 1; -1 0 1];
            Py = [-1 -1 -1; 0 0 0; 1 1 1];
            Jx = convn(double(image), double(Px), 'same');
            Jy = convn(double(image), double(Py), 'same');
            edgeImage = uint8(abs(Jx) + abs(Jy) > threshold);
        end

        function edgeImage = roberts(app, image, threshold)
            Rx = [1 0; 0 -1];
            Ry = [0 1; -1 0];
            Jx = convn(double(image), double(Rx), 'same');
            Jy = convn(double(image), double(Ry), 'same');
            edgeImage = uint8(abs(Jx) + abs(Jy) > threshold);

        end
        function edgeImage = canny(app, image, threshold)
            edgeImage = edge(image, "Canny", threshold/255);
        end
    

        %Fungsi fungsi utama
        function edgeImage = generateEdgeImage(app, image, method, threshold)
            switch method
                case 'laplace'
                    edgeImage = laplace(app, image, threshold);
                case 'LoG'
                    edgeImage = LoG(app, image, threshold);
                case 'sobel'
                    edgeImage = sobel(app, image, threshold);
                case 'prewitt'
                    edgeImage= prewitt(app, image, threshold);
                case 'roberts'
                    edgeImage = roberts(app, image, threshold);
                case 'canny'
                    edgeImage = canny(app, image, threshold);
                otherwise
                    edgeImage = image;
            end
        end

        function maskImage = generateMask(app, edgeImage, threshold)
            % bersihkan pixel tepi
            tempImage = imclearborder(edgeImage);
    
            % hubungkan tepi
            tempImage = imclose(tempImage, strel('disk', round(threshold)));
    
            % Isi daerah yang tepinya terhubung
            tempImage = imfill(tempImage, 8, 'holes');
    
            % erosi pada tepi yang bukan daerah utama
            tempImage = imerode(tempImage, strel('disk', 2));
    
            maskImage = uint8(tempImage);
        end

        function loadOriImage(app)
            [name, extention] = uigetfile({'*.jpg;*.tif;*.png;*.gif'}, 'Pick an image');
            if ischar(name)
                filename = [extention, name];
                loadedImage = imread(filename);
                app.OriginalImage = loadedImage;
                main(app);
            end
        end

        function main(app)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            imshow(app.OriginalImage, 'Parent', app.UIAxes);
            %ubah ke rgb untuk selanjutnya diproses tepinya
            grayImage = rgb2gray(app.OriginalImage);
            %dilakukan blurring untuk menghilangkan noise
            blurImage = imgaussfilt(grayImage, app.sigmaEditField.Value);
            %ambil edge
            edgeImage = generateEdgeImage(app, blurImage, app.edgedetectionDropDown.Value, app.edgethresholdSlider.Value);
            imshow(edgeImage, "Parent", app.UIAxes2);
            %buat mask
            maskImage = generateMask(app, edgeImage, app.segmentationtresholdSlider.Value);
            imshow(maskImage.*255, "Parent", app.UIAxes3);
            %yang sesuai dengan mask ditampilkan
            resultImage = app.OriginalImage .* maskImage;
            imshow(resultImage, "Parent", app.UIAxes4);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            loadOriImage(app);
        end

        % Value changed function: edgethresholdSlider
        function edgethresholdSliderValueChanged(app, event)
            main(app);
        end

        % Value changed function: segmentationtresholdSlider
        function segmentationtresholdSliderValueChanged(app, event)
            main(app);
        end

        % Value changed function: sigmaEditField
        function sigmaEditFieldValueChanged(app, event)
            main(app);
        end

        % Value changed function: edgedetectionDropDown
        function edgedetectionDropDownValueChanged(app, event)
            main(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Edge Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Mask Image')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Result Image')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.ChooseImageButton = uibutton(app.UIFigure, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.GaussianFilterCheckBox = uicheckbox(app.UIFigure);
            app.GaussianFilterCheckBox.Text = 'Gaussian Filter';
            app.edgedetectionDropDownLabel = uilabel(app.UIFigure);
            app.edgedetectionDropDownLabel.Text = 'edge detection';
            app.edgedetectionDropDown = uidropdown(app.UIFigure);
            app.edgedetectionDropDown.Items = {'laplace', 'LoG', 'sobel', 'prewitt', 'roberts', 'canny'};
            app.edgedetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @edgedetectionDropDownValueChanged, true);
            app.edgedetectionDropDown.Value = 'canny';
            app.edgethresholdSliderLabel = uilabel(app.UIFigure);
            app.edgethresholdSliderLabel.Text = 'edge threshold';
            app.edgethresholdSlider = uislider(app.UIFigure);
            app.edgethresholdSlider.Limits = [0 255];
            app.edgethresholdSlider.ValueChangedFcn = createCallbackFcn(app, @edgethresholdSliderValueChanged, true);
            app.edgethresholdSlider.Value = 30;
            app.segmentationtresholdSliderLabel = uilabel(app.UIFigure);
            app.segmentationtresholdSliderLabel.Text = 'segmentation treshold';
            app.segmentationtresholdSlider = uislider(app.UIFigure);
            app.segmentationtresholdSlider.ValueChangedFcn = createCallbackFcn(app, @segmentationtresholdSliderValueChanged, true);
            app.segmentationtresholdSlider.Value = 5;
            app.sigmaEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField.Limits = [0.01 Inf];
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaEditFieldValueChanged, true);
            app.sigmaEditField.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end